classdef FILTER_DESIGN_LABORATORY < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        OUTPUTPANELPanel             matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        GridLayout4                  matlab.ui.container.GridLayout
        PlotSelectorDropDown         matlab.ui.control.DropDown
        PlotSelectorDropDownLabel    matlab.ui.control.Label
        OpenPlotinNewWindowButton    matlab.ui.control.Button
        TextArea_4                   matlab.ui.control.TextArea
        TextArea_3                   matlab.ui.control.TextArea
        UIAxes5                      matlab.ui.control.UIAxes
        UIAxes6                      matlab.ui.control.UIAxes
        UIAxes7                      matlab.ui.control.UIAxes
        UIAxes4                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        CONTROLPANELPanel            matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        DESIGNFILTERButton           matlab.ui.control.Button
        RESISTANCEEditField          matlab.ui.control.NumericEditField
        RESISTANCEEditFieldLabel     matlab.ui.control.Label
        CUTOFFFREQ2EditField         matlab.ui.control.NumericEditField
        CUTOFFFREQ2EditFieldLabel    matlab.ui.control.Label
        CUTOFFFREQEditField          matlab.ui.control.NumericEditField
        CUTOFFFREQEditFieldLabel     matlab.ui.control.Label
        STOPBANDEditField            matlab.ui.control.NumericEditField
        STOPBANDEditFieldLabel       matlab.ui.control.Label
        RIPPLEFACTOREditField        matlab.ui.control.NumericEditField
        RIPPLEFACTOREditFieldLabel   matlab.ui.control.Label
        ORDERNEditField              matlab.ui.control.NumericEditField
        ORDERNEditFieldLabel         matlab.ui.control.Label
        APPROXIMATIONDropDown        matlab.ui.control.DropDown
        APPROXIMATIONDropDownLabel   matlab.ui.control.Label
        TYPEDropDown                 matlab.ui.control.DropDown
        TYPEDropDownLabel            matlab.ui.control.Label
        FILTERDESIGNLABORATORYLabel  matlab.ui.control.Label
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

                % TextArea for RLC values
                app.TextArea_4.Value = {
                    '--- RLC VALUES ---'
                    'R, L, C and Q will be shown here'
                };
                % TextArea for transfer function
                app.TextArea_3.Value = {
                    '--- TRANSFER FUNCTION ---'
                    'H(s) of the designed filter'
                    'will be displayed here'
                };
        end

        % Button pushed function: DESIGNFILTERButton
        function DESIGNFILTERButtonPushed(app, event)
            
            % ----- Read inputs -----
            n  = app.ORDERNEditField.Value;
            Rp = app.RIPPLEFACTOREditField.Value;
            Rs = app.STOPBANDEditField.Value;
            R_des = app.RESISTANCEEditField.Value;
            fc1 = app.CUTOFFFREQEditField.Value;
            fc2 = app.CUTOFFFREQ2EditField.Value;
            % ===== INPUT VALIDATION =====
            % Order check
            if isempty(n) || n <= 0 || mod(n,1) ~= 0
                uialert(app.UIFigure,'Filter order N must be a positive integer.','Invalid Input');
                return
            end
            % Resistance check
            if isempty(R_des) || R_des <= 0 || mod(R_des,1) ~= 0
                uialert(app.UIFigure,'Resistance must be a positive integer value.','Invalid Input');
                return
            end
            % Cutoff frequency check
            if isempty(fc1) || fc1 <= 0
                uialert(app.UIFigure,'Cutoff frequency must be positive.','Invalid Input');
                return
            end
            % Band filter frequency consistency
            if ismember(app.TYPEDropDown.Value,{'BAND PASS','BAND STOP'})
                if isempty(fc2) || fc2 <= fc1
                    uialert(app.UIFigure,'For band filters, F2 must be greater than F1.','Invalid Input');
                    return
                end
            end
            % Approximation-specific constraints
            switch app.APPROXIMATIONDropDown.Value
                case 'CHEBYSHEV-1'
                    if Rp <= 0
                        uialert(app.UIFigure,'Chebyshev-I requires ripple Rp > 0 dB.','Invalid Input');
                        return
                    end
                case 'CHEBYSHEV-2'
                    if Rs <= 0
                        uialert(app.UIFigure,'Chebyshev-II requires stopband attenuation Rs > 0 dB.','Invalid Input');
                        return
                    end
                case 'ELLIPTIC'
                    if Rp <= 0 || Rs <= 0
                        uialert(app.UIFigure,'Elliptic filter requires ripple Rp > 0 and Stopband attenuation Rs > 0.','Invalid Input');
                        return
                    end
                    if Rs <= Rp
                        uialert(app.UIFigure,'For Elliptic filters: Stopband attenuation (Rs) must be GREATER than passband ripple (Rp).','Invalid Input');
                        return
                    end    
            end
            % ===== END INPUT VALIDATION =====
            % ----- Filter type -----
            switch app.TYPEDropDown.Value
                case 'LOW PASS'
                    ftype = 'low';
                    wp = 2*pi*app.CUTOFFFREQEditField.Value;
            
                case 'HIGH PASS'
                    ftype = 'high';
                    wp = 2*pi*app.CUTOFFFREQEditField.Value;
            
                case 'BAND PASS'
                    ftype = 'bandpass';
                    wp = 2*pi*[app.CUTOFFFREQEditField.Value ...
                               app.CUTOFFFREQ2EditField.Value];
                case 'BAND STOP'
                    ftype = 'stop';
                    wp = 2*pi*[app.CUTOFFFREQEditField.Value ...
                               app.CUTOFFFREQ2EditField.Value];
            end
            % ----- Approximation -----
            switch app.APPROXIMATIONDropDown.Value
                case 'BUTTERWORTH'
                    [z,p,k] = butter(n, wp, ftype, 's');
            
                case 'CHEBYSHEV-1'
                    [z,p,k] = cheby1(n, Rp, wp, ftype, 's');
            
                case 'CHEBYSHEV-2'
                    [z,p,k] = cheby2(n, Rs, wp, ftype, 's');
            
                case 'ELLIPTIC'
                    [z,p,k] = ellip(n, Rp, Rs, wp, ftype, 's');
            end        
            sys = zpk(z,p,k);
            % Normalize only for BAND PASS (not BAND STOP)
            if strcmp(app.TYPEDropDown.Value,'BAND PASS')
                wc = sqrt(wp(1)*wp(2));
                Hc = abs(freqresp(sys, wc));
                sys = sys / Hc;
            end
            % ----- Frequency response -----
            if numel(wp) == 1
                w = logspace(log10(wp/20), log10(wp*20), 2000);
            else
                w = logspace(log10(min(wp)*0.5), log10(max(wp)*2), 2000);
            end
            H = squeeze(freqresp(sys,w));
            mag = abs(H);
            phase = unwrap(angle(H));
            % ----- Group delay -----
            dphi = diff(phase);
            dw   = diff(log(w));
            gd   = -dphi ./ (w(1:end-1).*dw);
            wgd  = w(1:end-1);
            app.UIFigure.UserData = struct( ...
                'sys',   sys, ...
                'w',     w, ...
                'mag',   mag, ...
                'phase', phase, ...
                'gd',    gd, ...
                'wgd',   wgd ...
            );
            % ----- Plots -----
            % Bode magnitude
            cla(app.UIAxes,'reset')
            semilogx(app.UIAxes,w,20*log10(mag),'LineWidth',1.5)
            grid(app.UIAxes,'on')
            title(app.UIAxes,'BODE PLOT')
            xlabel(app.UIAxes,'Frequency (rad/s)')
            ylabel(app.UIAxes,'Magnitude (dB)')
            % Phase
            cla(app.UIAxes2,'reset')
            semilogx(app.UIAxes2,w,phase*180/pi,'LineWidth',1.5)
            grid(app.UIAxes2,'on')
            title(app.UIAxes2,'PHASE RESPONSE')
            xlabel(app.UIAxes2,'Frequency (rad/s)')
            ylabel(app.UIAxes2,'Phase (degrees)')
            % Pole-zero map 
            cla(app.UIAxes3,'reset')
            pzmap(app.UIAxes3, sys)
            grid(app.UIAxes3,'on')
            box(app.UIAxes3,'on')
            p = pole(sys);
            z = zero(sys);
            allVals = [p; z];
            if ~isempty(allVals)
                xr = max(1, max(abs(real(allVals)))) * 1.5;
                yi = max(abs(imag(allVals))) * 1.2;
                if ~isfinite(yi) || yi <= 0
                    yi = 1;   
                end
                xlim(app.UIAxes3, [-xr xr])
                ylim(app.UIAxes3, [-yi yi])
            end
            title(app.UIAxes3,'Pole-Zero Map')
            xlabel(app.UIAxes3,'Real Axis (s^{-1})')
            ylabel(app.UIAxes3,'Imaginary Axis (s^{-1})')
            % Linear magnitude
            cla(app.UIAxes4,'reset')
            semilogx(app.UIAxes4,w,mag,'LineWidth',1.5)
            grid(app.UIAxes4,'on')
            title(app.UIAxes4,'Magnitude (Linear Scale)')
            xlabel(app.UIAxes4,'Frequency (rad/s)')
            ylabel(app.UIAxes4,'Magnitude')
            % Group delay
            cla(app.UIAxes5,'reset')
            semilogx(app.UIAxes5,wgd,gd,'LineWidth',1.5)
            grid(app.UIAxes5,'on')
            title(app.UIAxes5,'GROUP DELAY')
            xlabel(app.UIAxes5,'Frequency (rad/s)')
            ylabel(app.UIAxes5,'Delay (s)')
            % Impulse response  
            cla(app.UIAxes6,'reset')
            impulse(app.UIAxes6,sys)
            grid(app.UIAxes6,'on')
            box(app.UIAxes6,'on')
            title(app.UIAxes6,'IMPULSE RESPONSE')
            xlabel(app.UIAxes6,'Time (s)')
            ylabel(app.UIAxes6,'Amplitude')
            % Step response  
            cla(app.UIAxes7,'reset')
            step(app.UIAxes7,sys)
            grid(app.UIAxes7,'on')
            box(app.UIAxes7,'on')
            title(app.UIAxes7,'STEP RESPONSE')
            xlabel(app.UIAxes7,'Time (s)')
            ylabel(app.UIAxes7,'Amplitude')
            % ----- RLC + Q -----
            if numel(wp)==1
                wc = wp;
                Q = 1/sqrt(2);
            else
                wc = sqrt(wp(1)*wp(2));
                Q = wc/(wp(2)-wp(1));
            end
            R = R_des;
            L = R_des/wc;
            C = 1/(R_des*wc);
            % ----- Text output -----
            app.TextArea_4.Value = {
                '--- RLC VALUES ---'
                sprintf('R = %.2f Ohms',R)
                sprintf('L = %.6f H',L)
                sprintf('C = %.6e F',C)
                sprintf('Q ≈ %.2f',Q)
            };
            app.TextArea_3.Value = evalc('sys');
        end

        % Value changed function: TYPEDropDown
        function TYPEDropDownValueChanged(app, event)

            value = app.TYPEDropDown.Value;
            if ismember(value, {'BAND PASS','BAND STOP'})
                app.CUTOFFFREQ2EditField.Enable = 'on';
            else
                app.CUTOFFFREQ2EditField.Enable = 'off';
                app.CUTOFFFREQ2EditField.Value  = 0;
            end
        end

        % Button pushed function: OpenPlotinNewWindowButton
        function OpenPlotinNewWindowButtonPushed(app, event)
                
                    % Get system safely
                    data = app.UIFigure.UserData;                    sys  = data.sys;
                    if isempty(sys) || ~isa(sys,'lti')
                        uialert(app.UIFigure,'Please design the filter first.','No System');
                        return
                    end
                    % Create new figure and axes
                    fig = uifigure('Name','Expanded Plot','Position',[200 100 900 700]);
                    newAx = uiaxes(fig);
                    newAx.Position = [50 50 800 600];
                    cla(newAx,'reset')
                    % Select source axes
                    switch app.PlotSelectorDropDown.Value
                        case 'BODE PLOT'
                            srcAx = app.UIAxes;
                        case 'PHASE RESPONSE'
                            srcAx = app.UIAxes2;
                        case 'Pole-Zero Map'
                            srcAx = app.UIAxes3;
                        case 'Magnitude (Linear Scale)'
                            srcAx = app.UIAxes4;
                        case 'GROUP DELAY'
                            srcAx = app.UIAxes5;
                        case 'IMPULSE RESPONSE'
                            srcAx = app.UIAxes6;
                        case 'STEP RESPONSE'
                            srcAx = app.UIAxes7;
                    end
                    % Plot correctly
                    switch app.PlotSelectorDropDown.Value
                      
                        case 'BODE PLOT'
                            semilogx(newAx, data.w, 20*log10(data.mag), 'LineWidth', 1.5)
                        
                        case 'PHASE RESPONSE'
                            semilogx(newAx, data.w, data.phase*180/pi, 'LineWidth', 1.5)
                        
                        case 'Magnitude (Linear Scale)'
                            semilogx(newAx, data.w, data.mag, 'LineWidth', 1.5)
                        
                        case 'GROUP DELAY'
                            semilogx(newAx, data.wgd, data.gd, 'LineWidth', 1.5)

                        case 'Pole-Zero Map'
                            pzmap(newAx,sys)
                            grid(newAx,'on')
                            box(newAx,'on')
                            p = pole(sys);
                            z = zero(sys);
                            allVals = [p; z];
                            if ~isempty(allVals)
                                xr = max(1, max(abs(real(allVals)))) * 1.5;
                                yi = max(abs(imag(allVals))) * 1.2;
                                if ~isfinite(yi) || yi <= 0
                                    yi = 1;
                                end
                                xlim(newAx, [-xr xr])
                                ylim(newAx, [-yi yi])
                            end
                        case 'IMPULSE RESPONSE'
                            impulse(newAx,sys)
                            newAx.XLimMode = 'auto';
                            newAx.YLimMode = 'auto';
                            box(newAx,'on')
                        case 'STEP RESPONSE'
                            step(newAx,sys)
                            newAx.XLimMode = 'auto';
                            newAx.YLimMode = 'auto';
                            box(newAx,'on')
                    end
                    % Copy labels and grid          
                    switch app.PlotSelectorDropDown.Value
                            case 'BODE PLOT'
                                title(newAx,'BODE PLOT')
                                xlabel(newAx,'Frequency (rad/s)')
                                ylabel(newAx,'Magnitude (dB)')
                            case 'PHASE RESPONSE'
                                title(newAx,'PHASE RESPONSE')
                                xlabel(newAx,'Frequency (rad/s)')
                                ylabel(newAx,'Phase (degrees)')
                            case 'Pole-Zero Map'
                                title(newAx,'Pole-Zero Map')
                                xlabel(newAx,'Real Axis (s^{-1})')
                                ylabel(newAx,'Imaginary Axis (s^{-1})')
                            case 'Magnitude (Linear Scale)'
                                title(newAx,'Magnitude (Linear Scale)')
                                xlabel(newAx,'Frequency (rad/s)')
                                ylabel(newAx,'Magnitude')
                            case 'GROUP DELAY'
                                title(newAx,'GROUP DELAY')
                                xlabel(newAx,'Frequency (rad/s)')
                                ylabel(newAx,'Delay (s)')
                            case 'IMPULSE RESPONSE'
                                title(newAx,'IMPULSE RESPONSE')
                                xlabel(newAx,'Time (s)')
                                ylabel(newAx,'Amplitude')
                            case 'STEP RESPONSE'
                                title(newAx,'STEP RESPONSE')
                                xlabel(newAx,'Time (s)')
                                ylabel(newAx,'Amplitude')
                        end
                        
                        xlim(newAx, srcAx.XLim)
                        ylim(newAx, srcAx.YLim)
                        grid(newAx,'on')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.FILTERDESIGNLABORATORYLabel = uilabel(app.GridLayout);
            app.FILTERDESIGNLABORATORYLabel.Text = 'FILTER DESIGN LABORATORY';
            app.CONTROLPANELPanel = uipanel(app.GridLayout);
            app.CONTROLPANELPanel.Title = 'CONTROL PANEL';
            app.GridLayout2 = uigridlayout(app.CONTROLPANELPanel);
            app.TYPEDropDownLabel = uilabel(app.GridLayout2);
            app.TYPEDropDownLabel.Text = 'TYPE';
            app.TYPEDropDown = uidropdown(app.GridLayout2);
            app.TYPEDropDown.Items = {'LOW PASS', 'HIGH PASS', 'BAND PASS', 'BAND STOP'};
            app.TYPEDropDown.ValueChangedFcn = createCallbackFcn(app, @TYPEDropDownValueChanged, true);
            app.TYPEDropDown.Value = 'LOW PASS';
            app.APPROXIMATIONDropDownLabel = uilabel(app.GridLayout2);
            app.APPROXIMATIONDropDownLabel.Text = 'APPROXIMATION';
            app.APPROXIMATIONDropDown = uidropdown(app.GridLayout2);
            app.APPROXIMATIONDropDown.Items = {'BUTTERWORTH', 'CHEBYSHEV-1', 'CHEBYSHEV-2', 'ELLIPTIC'};
            app.APPROXIMATIONDropDown.Value = 'BUTTERWORTH';
            app.ORDERNEditFieldLabel = uilabel(app.GridLayout2);
            app.ORDERNEditFieldLabel.Text = 'ORDER N';
            app.ORDERNEditField = uieditfield(app.GridLayout2, 'numeric');
            app.RIPPLEFACTOREditFieldLabel = uilabel(app.GridLayout2);
            app.RIPPLEFACTOREditFieldLabel.Text = 'RIPPLE FACTOR';
            app.RIPPLEFACTOREditField = uieditfield(app.GridLayout2, 'numeric');
            app.STOPBANDEditFieldLabel = uilabel(app.GridLayout2);
            app.STOPBANDEditFieldLabel.Text = 'STOPBAND ';
            app.STOPBANDEditField = uieditfield(app.GridLayout2, 'numeric');
            app.CUTOFFFREQEditFieldLabel = uilabel(app.GridLayout2);
            app.CUTOFFFREQEditFieldLabel.Text = 'CUTOFF FREQ';
            app.CUTOFFFREQEditField = uieditfield(app.GridLayout2, 'numeric');
            app.CUTOFFFREQ2EditFieldLabel = uilabel(app.GridLayout2);
            app.CUTOFFFREQ2EditFieldLabel.Text = 'CUTOFF FREQ2';
            app.CUTOFFFREQ2EditField = uieditfield(app.GridLayout2, 'numeric');
            app.RESISTANCEEditFieldLabel = uilabel(app.GridLayout2);
            app.RESISTANCEEditFieldLabel.Text = 'RESISTANCE';
            app.RESISTANCEEditField = uieditfield(app.GridLayout2, 'numeric');
            app.DESIGNFILTERButton = uibutton(app.GridLayout2, 'push');
            app.DESIGNFILTERButton.ButtonPushedFcn = createCallbackFcn(app, @DESIGNFILTERButtonPushed, true);
            app.DESIGNFILTERButton.Text = 'DESIGN FILTER';
            app.OUTPUTPANELPanel = uipanel(app.GridLayout);
            app.OUTPUTPANELPanel.Title = 'OUTPUT PANEL';
            app.GridLayout3 = uigridlayout(app.OUTPUTPANELPanel);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Bode Plot')
            xlabel(app.UIAxes, 'Frequency (rad/s)')
            ylabel(app.UIAxes, 'Magnitude (dB)')
            app.UIAxes2 = uiaxes(app.GridLayout3);
            title(app.UIAxes2, 'Phase Response')
            xlabel(app.UIAxes2, 'Frequency (rad/s)')
            ylabel(app.UIAxes2, 'Phase (degrees)')
            app.UIAxes3 = uiaxes(app.GridLayout3);
            title(app.UIAxes3, 'Pole-Zero Map')
            xlabel(app.UIAxes3, 'Real Axis')
            ylabel(app.UIAxes3, 'Imaginary Axis')
            app.UIAxes4 = uiaxes(app.GridLayout3);
            title(app.UIAxes4, 'Magnitude (Linear Scale)')
            xlabel(app.UIAxes4, 'Frequency (rad/s)')
            ylabel(app.UIAxes4, 'Magnitude')
            app.UIAxes7 = uiaxes(app.GridLayout3);
            title(app.UIAxes7, 'Step Response')
            xlabel(app.UIAxes7, 'Time (s)')
            ylabel(app.UIAxes7, 'Amplitude')
            app.UIAxes6 = uiaxes(app.GridLayout3);
            title(app.UIAxes6, 'Impulse Response')
            xlabel(app.UIAxes6, 'Time (s)')
            ylabel(app.UIAxes6, 'Amplitude')
            app.UIAxes5 = uiaxes(app.GridLayout3);
            title(app.UIAxes5, 'Group Delay')
            xlabel(app.UIAxes5, 'Frequency (rad/s)')
            ylabel(app.UIAxes5, 'Delay (s)')
            app.TextArea_3 = uitextarea(app.GridLayout3);
            app.TextArea_4 = uitextarea(app.GridLayout3);
            app.OpenPlotinNewWindowButton = uibutton(app.GridLayout3, 'push');
            app.OpenPlotinNewWindowButton.ButtonPushedFcn = createCallbackFcn(app, @OpenPlotinNewWindowButtonPushed, true);
            app.OpenPlotinNewWindowButton.Text = 'Open Plot in New Window';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.PlotSelectorDropDownLabel = uilabel(app.GridLayout4);
            app.PlotSelectorDropDownLabel.Text = 'Plot Selector';
            app.PlotSelectorDropDown = uidropdown(app.GridLayout4);
            app.PlotSelectorDropDown.Items = {'BODE PLOT', 'PHASE RESPONSE', 'Pole-Zero Map', 'Magnitude (Linear Scale)', 'GROUP DELAY', 'IMPULSE RESPONSE', 'STEP RESPONSE'};
            app.PlotSelectorDropDown.Value = 'BODE PLOT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FILTER_DESIGN_LABORATORY

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end